classdef LoadingPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadData                        matlab.ui.Figure
        LoadInfrastructuresDataPanel    matlab.ui.container.Panel
        LoadStDb                        matlab.ui.control.Button
        StructuresDatabaseEditFieldLabel  matlab.ui.control.Label
        StructuresDatabaseEditField     matlab.ui.control.EditField
        LoadSeDb                        matlab.ui.control.Button
        InspectionsDatabaseEditFieldLabel  matlab.ui.control.Label
        InspectionsDatabaseEditField    matlab.ui.control.EditField
        LoadIntDb                       matlab.ui.control.Button
        InterventionsDatabaseEditFieldLabel  matlab.ui.control.Label
        InterventionsDatabaseEditField  matlab.ui.control.EditField
        LoadBudDb                       matlab.ui.control.Button
        BudgetDatabaseEditFieldLabel    matlab.ui.control.Label
        BudgetDatabaseEditField         matlab.ui.control.EditField
        LoadInspDb                      matlab.ui.control.Button
        InspectorsDatabaseEditFieldLabel  matlab.ui.control.Label
        InspectorsDatabaseEditField     matlab.ui.control.EditField
        LoadDetDb                       matlab.ui.control.Button
        ElementsDetailsEditFieldLabel   matlab.ui.control.Label
        ElementsDetailsEditField        matlab.ui.control.EditField
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainWindow   % Main App
        SE           % structural elements Database
        ST           % Structures Database
        IP           % Inspectors Database
        IN           % Intervention Database
        IB           % Budget Database
        ED           % Element Details
        CurrentPath=[];  % Files Path
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
           app.MainWindow = MainApp;
        end

        % Button pushed function: LoadStDb
        function LoadStDbPushed(app, event)
            addpath(sprintf('%s/Scripts/Load/',pwd))
            run('LoadStructures.m');
        end

        % Button pushed function: LoadSeDb
        function LoadSeDbButtonPushed(app, event)
            addpath(sprintf('%s/Scripts/Load/',pwd))
            run('LoadElements.m');
        end

        % Button pushed function: LoadIntDb
        function LoadIntDbButtonPushed(app, event)
            addpath(sprintf('%s/Scripts/Load/',pwd))
            run('LoadInterventions.m');
        end

        % Button pushed function: LoadBudDb
        function LoadBudDbButtonPushed(app, event)
            addpath(sprintf('%s/Scripts/Load/',pwd))
            run('LoadBudget.m');
        end

        % Button pushed function: LoadInspDb
        function LoadInspDbButtonPushed(app, event)
            addpath(sprintf('%s/Scripts/Load/',pwd))
            run('LoadInspectors.m');
        end

        % Button pushed function: LoadDetDb
        function LoadDetDbButtonPushed(app, event)
            addpath(sprintf('%s/Scripts/Load/',pwd))
            run('LoadElementsDetails.m');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            PassValues(app.MainWindow,app.SE,app.ST,app.IP,app.IN,app.IB,app.ED);
            close(app.LoadData);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.LoadData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadData = uifigure('Visible', 'off');
            app.LoadData.Name = 'Load Database';
            app.LoadInfrastructuresDataPanel = uipanel(app.LoadData);
            app.LoadInfrastructuresDataPanel.Title = 'Load Infrastructures Data';
            app.LoadStDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadStDb.ButtonPushedFcn = createCallbackFcn(app, @LoadStDbPushed, true);
            app.LoadStDb.Text = '...';
            app.StructuresDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.StructuresDatabaseEditFieldLabel.Text = 'Structures Database';
            app.StructuresDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadSeDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadSeDb.ButtonPushedFcn = createCallbackFcn(app, @LoadSeDbButtonPushed, true);
            app.LoadSeDb.Text = '...';
            app.InspectionsDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.InspectionsDatabaseEditFieldLabel.Text = 'Inspections Database';
            app.InspectionsDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadIntDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadIntDb.ButtonPushedFcn = createCallbackFcn(app, @LoadIntDbButtonPushed, true);
            app.LoadIntDb.Text = '...';
            app.InterventionsDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.InterventionsDatabaseEditFieldLabel.Text = 'Interventions Database';
            app.InterventionsDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadBudDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadBudDb.ButtonPushedFcn = createCallbackFcn(app, @LoadBudDbButtonPushed, true);
            app.LoadBudDb.Text = '...';
            app.BudgetDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.BudgetDatabaseEditFieldLabel.Text = 'Budget Database';
            app.BudgetDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadInspDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadInspDb.ButtonPushedFcn = createCallbackFcn(app, @LoadInspDbButtonPushed, true);
            app.LoadInspDb.Text = '...';
            app.InspectorsDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.InspectorsDatabaseEditFieldLabel.Text = 'Inspectors Database';
            app.InspectorsDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadDetDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadDetDb.ButtonPushedFcn = createCallbackFcn(app, @LoadDetDbButtonPushed, true);
            app.LoadDetDb.Text = '...';
            app.ElementsDetailsEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.ElementsDetailsEditFieldLabel.Text = 'Elements Details';
            app.ElementsDetailsEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.OKButton = uibutton(app.LoadData, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.LoadData, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.LoadData.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadingPanel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadData)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadData)
        end
    end
end