classdef ModelTraining < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelTrainingUIFigure          matlab.ui.Figure
        AboutMenu                      matlab.ui.container.Menu
        AboutModelTrainingNetworkMenu  matlab.ui.container.Menu
        ElementListBox                 matlab.ui.control.ListBox
        ParametersButton               matlab.ui.control.Button
        TrainModelButton               matlab.ui.control.Button
        ResumeTrainingButton           matlab.ui.control.Button
        LoadData                       matlab.ui.control.Button
        Tree                           matlab.ui.container.Tree
        SSMModelNode                   matlab.ui.container.TreeNode
        SSMKRModelNode                 matlab.ui.container.TreeNode
        RemoveButton                   matlab.ui.control.Button
        AssignButton                   matlab.ui.control.Button
        StartYearSpinner               matlab.ui.control.Spinner
        InspectionsstartedatLabel      matlab.ui.control.Label
        YearLabel                      matlab.ui.control.Label
        TestsetSpinnerLabel            matlab.ui.control.Label
        TestsetSpinner                 matlab.ui.control.Spinner
        TestsetSpinnerLabel_2          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SeDatabaseStore         % structural elements Database
        StDatabaseStore         % Structures Database
        InspectorsDatabaseStore % Inspectors Database
        IntDatabaseStore        % Intervention Database
        IntBudgetDataStore      % Budget Database
        ElementDetailsDataStore % Element Details
        AllElementsParameters   % Elements Parameters
    end
    
    methods (Access = public)
        
        function PassValues(app,SE,ST,IP,IN,IB,ED)
            app.SeDatabaseStore=SE;
            app.StDatabaseStore=ST;
            app.InspectorsDatabaseStore=IP;
            app.IntDatabaseStore=IN;
            app.IntBudgetDataStore=IB;
            app.ElementDetailsDataStore=ED;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                waitfor(LoadingPanel(app));
                % Deterioration Info:
                % 1: NOStruc % 2: DateInsp % 3: NoTrav % 4: Element % 5: NoElem % 6: Position
                % 7: Materiau % 8: TypeElement % 9: A % 10: B % 11: C % 12: D % 13: CEC
                % 14: Inspection ID % 15: Element ID
                SV=app.SeDatabaseStore.SelectedVariableNames;
                app.SeDatabaseStore.SelectedVariableNames=SV(4);
                app.ElementListBox.Items=unique(table2cell(readall(app.SeDatabaseStore)));
                app.AllElementsParameters=app.ElementListBox.Items';
                app.SeDatabaseStore.SelectedVariableNames=SV;
            catch
                message = 'Input data is not loaded, loading data has failed due to one or more files not loaded correctly';
                uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
            end
        end

        % Button pushed function: LoadData
        function LoadDataButtonPushed(app, event)
            try
                waitfor(LoadingPanel(app));
                % Deterioration Info:
                % 1: NOStruc % 2: DateInsp % 3: NoTrav % 4: Element % 5: NoElem % 6: Position
                % 7: Materiau % 8: TypeElement % 9: A % 10: B % 11: C % 12: D % 13: CEC
                % 14: Inspection ID % 15: Element ID
                SV=app.SeDatabaseStore.SelectedVariableNames;
                app.SeDatabaseStore.SelectedVariableNames=SV(4);
                app.ElementListBox.Items=unique(table2cell(readall(app.SeDatabaseStore)));
                app.AllElementsParameters=app.ElementListBox.Items';
                app.SeDatabaseStore.SelectedVariableNames=SV;
            catch
                message = 'Input data is not loaded, loading data has failed due to one orr more files not loaded correctly';
                uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
            end
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            %SelectedCat=app.Tree.SelectedNodes.Text;
            rmpath(sprintf('%s/Tools/ModelTraining_Generic/',pwd));
            addpath(sprintf('%s/Tools/ModelTraining/',pwd));
            if strcmp(app.Tree.Children(2).Text,app.Tree.SelectedNodes.Parent.Text)
                BoundsMenuKR_App(app);
            elseif strcmp(app.Tree.Children(1).Text,app.Tree.SelectedNodes.Parent.Text)
                BoundsMenu_App(app);
            end
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            try
                ElemCat=app.ElementListBox.Value;
                if strcmp(app.Tree.Children(1).Text,app.Tree.SelectedNodes.Text)
                    uitreenode(app.SSMModelNode,'Text',string(ElemCat));
                elseif strcmp(app.Tree.Children(2).Text,app.Tree.SelectedNodes.Text)
                    uitreenode(app.SSMKRModelNode,'Text',string(ElemCat));
                end
            catch
                message = 'Select a deterioration model from the list, and then click "Assign"';
                uialert(app.ModelTrainingUIFigure,message, 'Warning', 'Icon', 'warning')
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            try
                for i=1:length(app.Tree.Children)
                    Vals(i)= strcmp(app.Tree.SelectedNodes.Text,app.Tree.Children(i).Text);
                end
                if sum(Vals)==0
                    app.Tree.SelectedNodes.delete;
                end
            catch
                message = 'Select a structural categoery from the list, and then click "Remove"';
                uialert(app.ModelTrainingUIFigure,message, 'Warning', 'Icon', 'warning')
            end
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            ResumeTraining=0;
            cla(app.UIAxes);
            ThisYear=date;
            ThisYear=str2double(ThisYear(end-3:end));
            TrainingWindow=ThisYear-app.StartYearSpinner.Value;
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd));
            FullPath=sprintf('%s/ExtractedData/',pwd);
            SavePath = sprintf('%s/Tools/ModelTraining/Params',pwd);
            try
                for model_i=1:length(app.Tree.Children) % model type iterations: 2: SSM-KR or 1: SSM
                    for elem_j=1:length(app.Tree.Children(model_i).Children)
                        ElementName= app.Tree.Children(model_i).Children(elem_j).Text;
                        app.Tree.SelectedNodes=app.Tree.Children(model_i).Children(elem_j);
                        Index=find(strcmp(app.AllElementsParameters(:,1),ElementName));
                        training_deterioration();
                        % Interventions optimization
                        ModelParamLocal=app.AllElementsParameters{Index,2}{8}(:,1)';
                        OptBoundsInt=app.AllElementsParameters{Index,2}{8}(:,2:3);
                        training_interventions();
                    end
                end
            catch ME
                message = ME.message;%'Model training has failed due to input or model parameters are not defined/loaded correctly, check the initial parameters values and bounds, also ensure loading the input data correctly.';
                uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
            end
        end

        % Button pushed function: ResumeTrainingButton
        function ResumeTrainingButtonPushed(app, event)
            ResumeTraining = 1;
            try
                [DataFileName,FilePath]=uigetfile('.mat','Select parameters file');
                if ~isempty(DataFileName)
                    AllElementsParameters=load(fullfile(FilePath,DataFileName));
                    AllElementsParameters=struct2cell(AllElementsParameters);
                    AllElementsParameters = AllElementsParameters{1};
                end
                [ConfigFileName,FilePath]=uigetfile('.mat','Select config file');
                if ~isempty(DataFileName)
                    config_opt=load(fullfile(FilePath,ConfigFileName));
                    app.AllElementsParameters=config_opt.config_opt.elements;
                    s_model = config_opt.config_opt.indexes(1);
                    s_elem = config_opt.config_opt.indexes(2);
                end
                cla(app.UIAxes);
                ThisYear=date;
                ThisYear=str2double(ThisYear(end-3:end));
                TrainingWindow=ThisYear-app.StartYearSpinner.Value;
                addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd));
                FullPath=sprintf('%s/ExtractedData/',pwd);
                SavePath = sprintf('%s/Tools/ModelTraining/Params',pwd);
                for model_i=s_model:length(app.Tree.Children) % model type iterations: 1: SSM-KR or 2: SSM
                    for elem_j=s_elem:length(app.Tree.Children(model_i).Children)
                        ElementName= app.Tree.Children(model_i).Children(elem_j).Text;
                        app.Tree.SelectedNodes=app.Tree.Children(model_i).Children(elem_j);
                        Index=find(strcmp(app.AllElementsParameters(:,1),ElementName));
                        training_deterioration();
                        % Interventions optimization
                        ModelParamLocal=app.AllElementsParameters{Index,2}{8}(:,1)';
                        OptBoundsInt=app.AllElementsParameters{Index,2}{8}(:,2:3);
                        training_interventions();
                    end
                    s_elem = 1;
                    ResumeTraining = 0;
                end
            catch ME
                message = ME.message;%'Resume training has failed due to input or model parameters are not defined/loaded correctly';
                uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
            end
        end

        % Menu selected function: AboutModelTrainingNetworkMenu
        function AboutModelTrainingNetworkMenuSelected(app, event)
            uialert(app.ModelTrainingUIFigure,[{'Model Training - Network Toolbox'};{''};{'Developed By: Zachary A. Hamida, Ph.D.'};{'Webpage:zachamida.github.io'};{''};{'Prof. James-A. Goulet Research Group'}],'Info',"Icon","info")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelTrainingUIFigure = uifigure('Visible', 'off');
            app.ModelTrainingUIFigure.Name = 'Model Training';
            app.AboutMenu = uimenu(app.ModelTrainingUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutModelTrainingNetworkMenu = uimenu(app.AboutMenu);
            app.AboutModelTrainingNetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutModelTrainingNetworkMenuSelected, true);
            app.AboutModelTrainingNetworkMenu.Text = 'About Model Training Network';
            app.ElementListBox = uilistbox(app.ModelTrainingUIFigure);
            app.ElementListBox.Items = {'None'};
            app.ElementListBox.Multiselect = 'on';
            app.ElementListBox.Value = {'None'};
            app.ParametersButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
            app.TrainModelButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.ResumeTrainingButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.ResumeTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeTrainingButtonPushed, true);
            app.ResumeTrainingButton.Text = 'Resume Training';
            app.LoadData = uibutton(app.ModelTrainingUIFigure, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadData.Text = 'Load Data';
            app.Tree = uitree(app.ModelTrainingUIFigure);
            app.SSMModelNode = uitreenode(app.Tree);
            app.SSMModelNode.Text = 'SSM Model';
            app.SSMKRModelNode = uitreenode(app.Tree);
            app.SSMKRModelNode.Text = 'SSM-KR Model';
            app.RemoveButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '<< Remove';
            app.AssignButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Text = 'Assign >>';
            app.StartYearSpinner = uispinner(app.ModelTrainingUIFigure);
            app.StartYearSpinner.Limits = [2007 Inf];
            app.StartYearSpinner.Value = 2007;
            app.InspectionsstartedatLabel = uilabel(app.ModelTrainingUIFigure);
            app.InspectionsstartedatLabel.Text = 'Inspections started at:';
            app.YearLabel = uilabel(app.ModelTrainingUIFigure);
            app.YearLabel.Text = 'Year';
            app.TestsetSpinnerLabel = uilabel(app.ModelTrainingUIFigure);
            app.TestsetSpinnerLabel.Text = 'Test set';
            app.TestsetSpinner = uispinner(app.ModelTrainingUIFigure);
            app.TestsetSpinner.Limits = [0 50];
            app.TestsetSpinner.Value = 5;
            app.TestsetSpinnerLabel_2 = uilabel(app.ModelTrainingUIFigure);
            app.TestsetSpinnerLabel_2.Text = '%';
            app.UIAxes = uiaxes(app.ModelTrainingUIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Log-Likelihood')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.ModelTrainingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelTraining

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelTrainingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelTrainingUIFigure)
        end
    end
end