classdef SyntheticPred_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        uipanel1                    matlab.ui.container.Panel
        LoadInspectionDataBox       matlab.ui.control.EditField
        LoadInspectionData          matlab.ui.control.Button
        LoadTrueInspectorDataBox    matlab.ui.control.EditField
        LoadTrueInspectorData       matlab.ui.control.Button
        LoadEstimatedInspectorBox   matlab.ui.control.EditField
        LoadEstimatedInspectorData  matlab.ui.control.Button
        text2                       matlab.ui.control.Label
        text3                       matlab.ui.control.Label
        text4                       matlab.ui.control.Label
        LoadRegressionModelTxtBox   matlab.ui.control.EditField
        LoadRegressionModelButton   matlab.ui.control.Button
        text4_2                     matlab.ui.control.Label
        uipanel2                    matlab.ui.container.Panel
        text5                       matlab.ui.control.Label
        ForecastTime                matlab.ui.control.EditField
        text6                       matlab.ui.control.Label
        ObservationsWindow          matlab.ui.control.EditField
        text7                       matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        NumStElements               matlab.ui.control.EditField
        text10                      matlab.ui.control.Label
        uipanel4                    matlab.ui.container.Panel
        CondSpeedAcc                matlab.ui.control.CheckBox
        CondScatter                 matlab.ui.control.CheckBox
        SpeedAccScatter             matlab.ui.control.CheckBox
        ForecastError               matlab.ui.control.CheckBox
        InitSpeedAccScatter         matlab.ui.control.CheckBox
        SpeedHistogram              matlab.ui.control.CheckBox
        CondCInterval               matlab.ui.control.CheckBox
        NumberSigma                 matlab.ui.control.CheckBox
        CIVIolation                 matlab.ui.control.CheckBox
        RunAnalysis                 matlab.ui.control.Button
        ExitSynPred                 matlab.ui.control.Button
        uipanel3                    matlab.ui.container.Panel
        ParamTable                  matlab.ui.control.Table
        text11                      matlab.ui.control.Label
        NumFigures                  matlab.ui.control.EditField
        RunAnalysis_2               matlab.ui.control.Button
    end

    
    properties (Access = public)
        InspectorsEstimatedSigma % Estimated standard deviation (Inspectors)
        SynInspectionDatabase    % Synthetic Inspections Database
        InspectorsTrueSigma      % True standard deviation (Inspectors)
        RegressionModel          % Regression Model Parameters
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SyntheticPred_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to SyntheticPred (see VARARGIN)
%             
%             % Choose default command line output for SyntheticPred
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes SyntheticPred wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
            load('DefaultTableParameters.mat')
            set(app.ParamTable,'Data',DefaultParameters);
            set(app.ForecastTime,'Value','10');
            set(app.ObservationsWindow,'Value','10');
            set(app.NumStElements,'Value','4000');
        end

        % Button pushed function: ExitSynPred
        function ExitSynPred_Callback(app, event)
            % hObject    handle to ExitSynPred (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(app.figure1);
        end

        % Button pushed function: LoadEstimatedInspectorData
        function LoadEstimatedInspectorData_Callback(app, event)
            % hObject    handle to LoadEstimatedInspectorData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            FilterSuffix={'*.mat';'*.*'};
            [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select a .mat file containing the Estimated Inspectors Sigma');
            set(app.LoadEstimatedInspectorBox,'Value',VIDataFileName)
            app.InspectorsEstimatedSigma=load(sprintf('%s\%s',FilePath,VIDataFileName));
            app.InspectorsEstimatedSigma=struct2cell(app.InspectorsEstimatedSigma);
        end

        % Button pushed function: LoadInspectionData
        function LoadInspectionData_Callback(app, event)
            % hObject    handle to LoadInspectionData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            FilterSuffix={'*.mat';'*.*'};
            [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Synthetic Inspections data file');
            set(app.LoadInspectionDataBox,'Value',VIDataFileName)
            app.SynInspectionDatabase=load(sprintf('%s\%s',FilePath,VIDataFileName));
            app.SynInspectionDatabase=struct2cell(app.SynInspectionDatabase);
        end

        % Button pushed function: LoadTrueInspectorData
        function LoadTrueInspectorData_Callback(app, event)
            % hObject    handle to LoadTrueInspectorData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            FilterSuffix={'*.mat';'*.*'};
            [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select a .mat file containing the True Inspectors Sigma');
            set(app.LoadTrueInspectorDataBox,'Value',VIDataFileName)
            app.InspectorsTrueSigma=load(sprintf('%s\%s',FilePath,VIDataFileName));
            app.InspectorsTrueSigma=struct2cell(app.InspectorsTrueSigma);
        end

        % Button pushed function: RunAnalysis
        function RunAnalysis_Callback(app, event)
            % hObject    handle to RunAnalysis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            TableOfParameters=get(app.ParamTable,'Data');
            M=str2double(get(app.ForecastTime,'Value'));
            ObsWindow=str2double(get(app.ObservationsWindow,'Value'));
            NumOfElements=str2double(get(app.NumStElements,'Value'));
            NumOfFigures=str2double(get(app.NumFigures,'Value'));
            GraphMatrix(1,1)=get(app.CondSpeedAcc,'value');GraphMatrix(1,2)=0;
            GraphMatrix(2,1)=get(app.ForecastError,'value');GraphMatrix(2,2)=get(app.SpeedHistogram,'value');
            GraphMatrix(3,1)=get(app.CondScatter,'value');GraphMatrix(3,2)=get(app.CondCInterval,'value');
            GraphMatrix(4,1)=get(app.SpeedAccScatter,'value');GraphMatrix(4,2)=get(app.NumberSigma,'value');
            GraphMatrix(5,1)=get(app.InitSpeedAccScatter,'value');GraphMatrix(5,2)=get(app.CIVIolation,'value');
            PredictSyntheticData(app.SynInspectionDatabase{1},app.SynInspectionDatabase{2},M,app.InspectorsTrueSigma{1},...
                app.InspectorsEstimatedSigma{1},ObsWindow,NumOfElements,cell2mat(app.RegressionModel), TableOfParameters,...
                GraphMatrix,NumOfFigures);
        end

        % Button pushed function: LoadRegressionModelButton
        function LoadRegressionModelButtonPushed(app, event)
            FilterSuffix={'*.mat';'*.*'};
            [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select a .mat file containing the Regression Model Parameters');
            set(app.LoadRegressionModelTxtBox,'Value',VIDataFileName)
            app.RegressionModel=load(sprintf('%s\%s',FilePath,VIDataFileName));
            app.RegressionModel=struct2cell(app.RegressionModel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Verify Synthetic Data';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Load Synthetic Data';
            app.LoadInspectionDataBox = uieditfield(app.uipanel1, 'text');
            app.LoadInspectionData = uibutton(app.uipanel1, 'push');
            app.LoadInspectionData.ButtonPushedFcn = createCallbackFcn(app, @LoadInspectionData_Callback, true);
            app.LoadInspectionData.Text = '...';
            app.LoadTrueInspectorDataBox = uieditfield(app.uipanel1, 'text');
            app.LoadTrueInspectorData = uibutton(app.uipanel1, 'push');
            app.LoadTrueInspectorData.ButtonPushedFcn = createCallbackFcn(app, @LoadTrueInspectorData_Callback, true);
            app.LoadTrueInspectorData.Text = '...';
            app.LoadEstimatedInspectorBox = uieditfield(app.uipanel1, 'text');
            app.LoadEstimatedInspectorData = uibutton(app.uipanel1, 'push');
            app.LoadEstimatedInspectorData.ButtonPushedFcn = createCallbackFcn(app, @LoadEstimatedInspectorData_Callback, true);
            app.LoadEstimatedInspectorData.Text = '...';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Load True & Observed Data';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Load True Inspectors Data';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Load Est. Inspectors Data';
            app.LoadRegressionModelTxtBox = uieditfield(app.uipanel1, 'text');
            app.LoadRegressionModelButton = uibutton(app.uipanel1, 'push');
            app.LoadRegressionModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRegressionModelButtonPushed, true);
            app.LoadRegressionModelButton.Text = '...';
            app.text4_2 = uilabel(app.uipanel1);
            app.text4_2.Text = 'Load Regression Model';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Analyses Parameters';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Text = 'Forecast Time';
            app.ForecastTime = uieditfield(app.uipanel2, 'text');
            app.text6 = uilabel(app.uipanel2);
            app.text6.Text = 'Years';
            app.ObservationsWindow = uieditfield(app.uipanel2, 'text');
            app.text7 = uilabel(app.uipanel2);
            app.text7.Text = 'Years';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Text = 'Observations Window';
            app.NumStElements = uieditfield(app.uipanel2, 'text');
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = 'Number of Structural Elements';
            app.uipanel4 = uipanel(app.uipanel2);
            app.uipanel4.Title = 'Graphs';
            app.CondSpeedAcc = uicheckbox(app.uipanel4);
            app.CondSpeedAcc.Text = 'Cond., Speed, Acc.';
            app.CondScatter = uicheckbox(app.uipanel4);
            app.CondScatter.Text = 'Cond. Scatter';
            app.SpeedAccScatter = uicheckbox(app.uipanel4);
            app.SpeedAccScatter.Text = 'Speed, Acc. Scatter';
            app.ForecastError = uicheckbox(app.uipanel4);
            app.ForecastError.Text = 'Forecast Err.';
            app.InitSpeedAccScatter = uicheckbox(app.uipanel4);
            app.InitSpeedAccScatter.Text = 'Speed, Acc. Scatter (Init)';
            app.SpeedHistogram = uicheckbox(app.uipanel4);
            app.SpeedHistogram.Text = 'Speed Hist.';
            app.CondCInterval = uicheckbox(app.uipanel4);
            app.CondCInterval.Text = 'Cond. C. I.';
            app.NumberSigma = uicheckbox(app.uipanel4);
            app.NumberSigma.Text = 'Num. Sigma';
            app.CIVIolation = uicheckbox(app.uipanel4);
            app.CIVIolation.Text = 'C. I. Violation';
            app.RunAnalysis = uibutton(app.figure1, 'push');
            app.RunAnalysis.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysis_Callback, true);
            app.RunAnalysis.Text = 'Run';
            app.ExitSynPred = uibutton(app.figure1, 'push');
            app.ExitSynPred.ButtonPushedFcn = createCallbackFcn(app, @ExitSynPred_Callback, true);
            app.ExitSynPred.Text = 'Exit';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Model Parameters';
            app.ParamTable = uitable(app.uipanel3);
            app.ParamTable.ColumnName = {'Sigma_W'; 'Sigma_Cond'; 'Sigma_Speed'; 'Sigma_Acc'; 'mu_Speed'; 'mu_Acc'; 'Min(Speed)'; 'n'};
            app.ParamTable.RowName = {'True'; 'Estimated'; ''; ''};
            app.ParamTable.ColumnEditable = [true true true true true true true true];
            app.text11 = uilabel(app.figure1);
            app.text11.Text = {'Save Time-series Results'; ' (Number of Cases)'};
            app.NumFigures = uieditfield(app.figure1, 'text');
            app.NumFigures.Value = '20';
            app.RunAnalysis_2 = uibutton(app.figure1, 'push');
            app.RunAnalysis_2.Text = 'Stop Run';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SyntheticPred_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @SyntheticPred_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end